classdef scgeatoolApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        FileMenu                    matlab.ui.container.Menu
        ImportDataMenu              matlab.ui.container.Menu
        ExportSaveDataMenu          matlab.ui.container.Menu
        CloseMenu                   matlab.ui.container.Menu
        EditMenu                    matlab.ui.container.Menu
        ViewMenu                    matlab.ui.container.Menu
        PlotsMenu                   matlab.ui.container.Menu
        AnnotateMenu                matlab.ui.container.Menu
        AnalyzeMenu                 matlab.ui.container.Menu
        NetworkMenu                 matlab.ui.container.Menu
        ExternalMenu                matlab.ui.container.Menu
        HelpMenu                    matlab.ui.container.Menu
        Toolbar                     matlab.ui.container.Toolbar
        PushTool_ImportData         matlab.ui.container.toolbar.PushTool
        PushTool_MultigroupingView  matlab.ui.container.toolbar.PushTool
        PushTool_CrossTabulation    matlab.ui.container.toolbar.PushTool
        PushTool4                   matlab.ui.container.toolbar.PushTool
        Toolbar2                    matlab.ui.container.Toolbar
        PushTool_ShowGeneExpr       matlab.ui.container.toolbar.PushTool
        PushTool6                   matlab.ui.container.toolbar.PushTool
        PushTool7                   matlab.ui.container.toolbar.PushTool
        PushTool8                   matlab.ui.container.toolbar.PushTool
        PushTool9                   matlab.ui.container.toolbar.PushTool
        PushTool10                  matlab.ui.container.toolbar.PushTool
        PushTool11                  matlab.ui.container.toolbar.PushTool
        PushTool12                  matlab.ui.container.toolbar.PushTool
        PushTool_SaveX              matlab.ui.container.toolbar.PushTool
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)        
        
    end
    
    properties (Access = public)
        sce = SingleCellExperiment;
    end
    
    methods (Access = private)
        
        function results = in_UpdateMainPlot(app)
            in_UpdatePushTool(app);
            if app.sce.NumCells == 0
                app.UIAxes.Visible = "off";
            else
                results = gui.i_gscatter3(app.sce.s, app.sce.c, 1, [], app.UIAxes);
                title(app.UIAxes, app.sce.title);
                subtitle(app.UIAxes, '[genes x cells]');
                app.UIAxes.Visible = "on";
            end
        end
        
        function results = in_UpdatePushTool(app)
            pushTools = i_GetConditionOnPushTool(app);
            tag = "on";
            if app.sce.NumCells == 0
                tag = "off";
            end
            for k = 1:length(pushTools)
                pushTools(k).Enable = tag;
            end
        end
        
        function results = i_GetAlwaysOnPushTool(app)
            results = [app.PushTool_ImportData];
        end
        
        function results = i_GetConditionOnPushTool(app)
            pushTools1 = i_GetAlwaysOnPushTool(app);
            pushTools2 = findall(app.UIFigure, 'Type', 'uipushtool');
            results = setdiff(pushTools2, pushTools1);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % pushTools = findall(app.UIFigure, 'Type', 'uipushtool');
            pushTools = i_GetConditionOnPushTool(app);
            for k = 1:length(pushTools)
                pushTools(k).Enable = "off";
            end
            app.UIAxes.Visible = "off";
            movegui(app.UIFigure, 'center')
        end

        % Menu selected function: CloseMenu
        function CloseMenuSelected(app, event)
            app.delete;
        end

        % Callback function: PushTool_ImportData
        function PushTool_ImportDataClicked(app, event)
            [res] = gui.sc_openscedlg([], [], app.UIFigure);
            if ~isempty(res)
                app.sce = res;                
            end
            app.in_UpdateMainPlot;
        end

        % Callback function: PushTool_ShowGeneExpr
        function PushTool_ShowGeneExprClicked(app, event)
            xui.callback_ShowGeneExpr(app, event);
        end

        % Callback function: PushTool_SaveX
        function PushTool_SaveXClicked(app, event)
            gui.i_isuifig(app.UIFigure)
            xui.callback_SaveX(app, event);
        end

        % Callback function: PushTool_MultigroupingView
        function PushTool_MultigroupingViewClicked(app, event)
            xui.callback_MultiGroupingView(app, event);
        end

        % Callback function: PushTool_CrossTabulation
        function PushTool_CrossTabulationClicked(app, event)
            xui.callback_CrossTabulation(app, event);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.ImportDataMenu = uimenu(app.FileMenu);
            app.ImportDataMenu.Text = 'Import Data...';
            app.ExportSaveDataMenu = uimenu(app.FileMenu);
            app.ExportSaveDataMenu.Text = 'Export/Save Data...';
            app.CloseMenu = uimenu(app.FileMenu);
            app.CloseMenu.MenuSelectedFcn = createCallbackFcn(app, @CloseMenuSelected, true);
            app.CloseMenu.Separator = 'on';
            app.CloseMenu.Text = 'Close';
            app.EditMenu = uimenu(app.UIFigure);
            app.EditMenu.Text = 'Edit';
            app.ViewMenu = uimenu(app.UIFigure);
            app.ViewMenu.Text = 'View';
            app.PlotsMenu = uimenu(app.UIFigure);
            app.PlotsMenu.Text = 'Plots';
            app.AnnotateMenu = uimenu(app.UIFigure);
            app.AnnotateMenu.Text = 'Annotate';
            app.AnalyzeMenu = uimenu(app.UIFigure);
            app.AnalyzeMenu.Text = 'Analyze';
            app.NetworkMenu = uimenu(app.UIFigure);
            app.NetworkMenu.Text = 'Network';
            app.ExternalMenu = uimenu(app.UIFigure);
            app.ExternalMenu.Text = 'External';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.PushTool_ImportData = uipushtool(app.Toolbar);
            app.PushTool_ImportData.ClickedCallback = createCallbackFcn(app, @PushTool_ImportDataClicked, true);
            app.PushTool_ImportData.Icon = fullfile(pathToMLAPP, 'assets', 'Images', 'box3d-center.jpg');
            app.PushTool_MultigroupingView = uipushtool(app.Toolbar);
            app.PushTool_MultigroupingView.Tooltip = {'Multi-grouping View...'};
            app.PushTool_MultigroupingView.ClickedCallback = createCallbackFcn(app, @PushTool_MultigroupingViewClicked, true);
            app.PushTool_MultigroupingView.Enable = 'off';
            app.PushTool_MultigroupingView.Icon = fullfile(pathToMLAPP, 'assets', 'Images', 'visibility_18dp_000000_FILL0_wght400_GRAD0_opsz20.jpg');
            app.PushTool_CrossTabulation = uipushtool(app.Toolbar);
            app.PushTool_CrossTabulation.Tooltip = {'Cross tabulation...'};
            app.PushTool_CrossTabulation.ClickedCallback = createCallbackFcn(app, @PushTool_CrossTabulationClicked, true);
            app.PushTool_CrossTabulation.Enable = 'off';
            app.PushTool_CrossTabulation.Icon = fullfile(pathToMLAPP, 'assets', 'Images', 'full_stacked_bar_chart_16dp_000000_FILL0_wght400_GRAD0_opsz20.jpg');
            app.PushTool4 = uipushtool(app.Toolbar);
            app.PushTool4.Separator = 'on';
            app.Toolbar2 = uitoolbar(app.UIFigure);
            app.PushTool_ShowGeneExpr = uipushtool(app.Toolbar2);
            app.PushTool_ShowGeneExpr.ClickedCallback = createCallbackFcn(app, @PushTool_ShowGeneExprClicked, true);
            app.PushTool_ShowGeneExpr.Enable = 'off';
            app.PushTool_ShowGeneExpr.Icon = fullfile(pathToMLAPP, 'assets', 'Images', 'google-docs.jpg');
            app.PushTool6 = uipushtool(app.Toolbar2);
            app.PushTool7 = uipushtool(app.Toolbar2);
            app.PushTool8 = uipushtool(app.Toolbar2);
            app.PushTool9 = uipushtool(app.Toolbar2);
            app.PushTool10 = uipushtool(app.Toolbar2);
            app.PushTool11 = uipushtool(app.Toolbar2);
            app.PushTool12 = uipushtool(app.Toolbar2);
            app.PushTool_SaveX = uipushtool(app.Toolbar2);
            app.PushTool_SaveX.ClickedCallback = createCallbackFcn(app, @PushTool_SaveXClicked, true);
            app.PushTool_SaveX.Icon = fullfile(pathToMLAPP, 'assets', 'Images', 'floppy-disk.jpg');
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = scgeatoolApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end